classdef Pedestal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        NewMenu             matlab.ui.container.Menu
        OpenMenu            matlab.ui.container.Menu
        SaveMenu            matlab.ui.container.Menu
        SaveAsMenu          matlab.ui.container.Menu
        CloseMenu           matlab.ui.container.Menu
        QuitMenu            matlab.ui.container.Menu
        ComponentsMenu      matlab.ui.container.Menu
        ElementsMenu        matlab.ui.container.Menu
        NewElementMenu      matlab.ui.container.Menu
        ImportElementMenu   matlab.ui.container.Menu
        ManageElementsMenu  matlab.ui.container.Menu
        RoutinesMenu        matlab.ui.container.Menu
        NewRoutineMenu      matlab.ui.container.Menu
        ImportRoutineMenu   matlab.ui.container.Menu
        ManageRoutinesMenu  matlab.ui.container.Menu
        EditDesignMenu      matlab.ui.container.Menu
        ExperimentMenu      matlab.ui.container.Menu
        RunMenu             matlab.ui.container.Menu
        StopMenu            matlab.ui.container.Menu
        CompileMenu         matlab.ui.container.Menu
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('Icons');
            im = imread('Splash.png'); %load splash image
            imsize = size(im); %get original image size
            imsize = imsize*(1000/max(imsize)); %transform so it fits within a 1000*1000 box
            
            splash = figure('MenuBar', 'none','NumberTitle','off','Position',[0,0,imsize(2),imsize(1)],'Visible','off'); %create figure
            splaxis = axes('Parent',splash,'Units', 'normalized', 'Position', [0 0 1 1]); %create axis
            
            image(im,'Parent',splaxis); %draw image
            set(splaxis,'TickDir','out') %remove ticks
            
            movegui(splash,'center'); %move to centre
            
            set(splash,'Visible','on')
            
            pause(2) %load content (placeholder)
            
            close all
            set(0,'units','pixels');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New...';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Text = 'Close';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.ComponentsMenu = uimenu(app.UIFigure);
            app.ComponentsMenu.Text = 'Components';
            app.ElementsMenu = uimenu(app.ComponentsMenu);
            app.ElementsMenu.Text = 'Elements';
            app.NewElementMenu = uimenu(app.ElementsMenu);
            app.NewElementMenu.Text = 'New Element';
            app.ImportElementMenu = uimenu(app.ElementsMenu);
            app.ImportElementMenu.Text = 'Import Element';
            app.ManageElementsMenu = uimenu(app.ElementsMenu);
            app.ManageElementsMenu.Text = 'Manage Elements';
            app.RoutinesMenu = uimenu(app.ComponentsMenu);
            app.RoutinesMenu.Text = 'Routines';
            app.NewRoutineMenu = uimenu(app.RoutinesMenu);
            app.NewRoutineMenu.Text = 'New Routine';
            app.ImportRoutineMenu = uimenu(app.RoutinesMenu);
            app.ImportRoutineMenu.Text = 'Import Routine';
            app.ManageRoutinesMenu = uimenu(app.RoutinesMenu);
            app.ManageRoutinesMenu.Text = 'Manage Routines';
            app.EditDesignMenu = uimenu(app.ComponentsMenu);
            app.EditDesignMenu.Text = 'Edit Design...';
            app.ExperimentMenu = uimenu(app.UIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.RunMenu = uimenu(app.ExperimentMenu);
            app.RunMenu.Text = 'Run';
            app.StopMenu = uimenu(app.ExperimentMenu);
            app.StopMenu.Text = 'Stop';
            app.CompileMenu = uimenu(app.ExperimentMenu);
            app.CompileMenu.Text = 'Compile...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Pedestal

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end